classdef interfacing_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Label                      matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        resetBtn                   matlab.ui.control.Button
        rotateRightBtn             matlab.ui.control.Button
        rotateLeftBtn              matlab.ui.control.Button
        screenshotBtn              matlab.ui.control.Button
        enableCameraBtn            matlab.ui.control.Button
        connectBtn                 matlab.ui.control.Button
        StatusLabel                matlab.ui.control.Label
        RobotStatusLamp            matlab.ui.control.Lamp
        RobotStatusLampLabel       matlab.ui.control.Label
        MasterPiControllerLabel    matlab.ui.control.Label
        Servo5                     matlab.ui.control.Slider
        Servo5BaseSliderLabel      matlab.ui.control.Label
        Servo4                     matlab.ui.control.Slider
        Servo4ArmSliderLabel       matlab.ui.control.Label
        Servo3                     matlab.ui.control.Slider
        Servo3WristSliderLabel     matlab.ui.control.Label
        Servo2                     matlab.ui.control.Slider
        Servo2CameraSliderLabel    matlab.ui.control.Label
        Servo1                     matlab.ui.control.Slider
        Servo1ClippersSliderLabel  matlab.ui.control.Label
        autoBtn                    matlab.ui.control.Button
        hiBtn                      matlab.ui.control.Button
        stopBtn                    matlab.ui.control.Button
        downBtn                    matlab.ui.control.Button
        leftBtn                    matlab.ui.control.Button
        rightBtn                   matlab.ui.control.Button
        upBtn                      matlab.ui.control.Button
        Camera                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ip = '10.42.0.1';
        port = 5000;
        keyTimer;
        isKeyPressed = false;
        tcpobj;
        cameratcp;
        pressedKey;
        isConnected = false;
        automode = false;
        uiLock = false;
        cameraEnabled = false;
        VideoTimer;
        StreamInput;


    end
    
    methods (Access = private)


        function startProgram(app)
            
            app.UIFigure.WindowKeyPressFcn   = @(~,event) keyDown(app, event);
            app.UIFigure.WindowKeyReleaseFcn = @(~,event) keyUp(app,event);
         end
        
        function keyDown(app,event)
            if app.isKeyPressed, return; end
                 app.isKeyPressed = true;
           
            app.pressedKey = lower(event.Key);
            app.keyTimer = timer( ...
                'ExecutionMode','fixedRate', ...
                'Period',0.03, ...  
                'TimerFcn', @(~,~) app.moveRobot());
            
            start(app.keyTimer);
        end


        function moveRobot(app)
              if app.isConnected
                switch app.pressedKey
                    case {'w','uparrow'}
                            app.TextArea.Value = "[Robot] Moving forward.";
                            writeline(app.tcpobj,"MOVE FWD");


                    case {'a','leftarrow'}
                            app.TextArea.Value = "[Robot] Turning left.";
                            writeline(app.tcpobj,"MOVE LEFT");
                            
                      
                    case {'s','downarrow'}
                            app.TextArea.Value = "[Robot] Moving backwards.";
                            writeline(app.tcpobj,"MOVE REV");
                            
                      
                    case {'d','rightarrow'}
                            app.TextArea.Value = "[Robot] Turning right.";
                            writeline(app.tcpobj,"MOVE RIGHT");
                            
                       
                    case {'e'}
                            app.TextArea.Value = "[Robot] Rotating CW.";
                            writeline(app.tcpobj,"ROTATE RIGHT");
                           
                    case {'q'}
                        app.TextArea.Value = "[Robot] Rotating CCW.";
                        writeline(app.tcpobj,"ROTATE LEFT");
                       
                    case {'v'}
                        app.TextArea.Value = "[Robot] Stopping.";
                        writeline(app.tcpobj, "STOP");
                    
       
                end
              else
                  app.TextArea.Value = "[System] Please connect to the robot to move it.";
              end
        end
    
        
        function keyUp(app, ~)
            app.isKeyPressed = false;
            writeline(app.tcpobj, "STOP");
            stop(app.keyTimer);
            
        end


        function handleVideo(app)
        
        try
        frame = imread('http://10.42.0.1:8080/frame.jpg'); 
        if ishandle(app.Camera)
            imshow(frame, 'Parent', app.Camera);
            axis(app.Camera, 'image');
            set(app.Camera, 'XTick', [], 'YTick', []);
            drawnow limitrate;
        end
        catch ME
        fprintf('[Camera] Error fetching frame: %s\n', ME.message);
        end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [100 100 1000 600];
            app.UIFigure.Resize = 'off';
            app.RobotStatusLamp.Color =[1 0 0];
            app.StatusLabel.Text = 'Disconnected';
            app.Servo1.Value = 135;
            app.Servo2.Value = 90;
            app.Servo3.Value = 90;
            app.Servo4.Value = 135;
            app.Servo5.Value = 90;
            app.TextArea.Value = "[System] Welcome to MasterPi Controller! Connect to start the robot!";
            app.Label.Text = "Alternatively, use WASD keys for movement, Q/E for rotate, V for stop";
            
            startProgram(app);


        end

        % Value changing function: Servo1
        function Servo1ValueChanging(app, event)
            app.TextArea.Value = "[Robot] Moving servo 1.";
            angle = round(event.Value);
            message = "SERVO 0 "+angle;
            writeline(app.tcpobj,message);
            
        end

        % Value changing function: Servo2
        function Servo2ValueChanging(app, event)
            app.TextArea.Value = "[Robot] Moving servo 2.";
            angle = round(event.Value);
            message = "SERVO 2 "+angle;
            writeline(app.tcpobj,message);
        end

        % Value changing function: Servo3
        function Servo3ValueChanging(app, event)
            app.TextArea.Value = "[Robot] Moving servo 3.";
            angle = round(event.Value);
            message = "SERVO 4 "+angle;
            writeline(app.tcpobj,message);
        end

        % Value changing function: Servo4
        function Servo4ValueChanging(app, event)
            app.TextArea.Value = "[Robot] Moving servo 4.";
            angle = round(event.Value);
            message = "SERVO 6 "+angle;
            writeline(app.tcpobj,message);
        end

        % Value changing function: Servo5
        function Servo5ValueChanging(app, event)
            app.TextArea.Value = "[Robot] Moving servo 5.";
            angle = round(event.Value);
            message = "SERVO 8 "+angle;
            writeline(app.tcpobj,message);
        end

        % Button pushed function: connectBtn
        function connectBtnButtonPushed(app, event)
           if ~app.isConnected
                app.RobotStatusLamp.Color = [1 0.5 0];
                app.StatusLabel.Text = "Connecting...";
                app.TextArea.Value = "[System] Connecting to robot...";
                
                drawnow;
                try
                    app.tcpobj = tcpclient(app.ip,app.port,"Timeout",5);
                    pause(0.5);
                    
                    app.StatusLabel.Text = "Connected";
                    app.RobotStatusLamp.Color = [0 1 0];
                    app.connectBtn.Text = "Disconnect";
                    app.isConnected = true;
                    app.TextArea.Value = "[System] Connected to robot!";
                    drawnow;
                    return;
                   
                    
                catch e
                    app.StatusLabel.Text = "Disconnected";
                    app.connectBtn.Text= "Reconnect";
                    
                    app.RobotStatusLamp.Color = [1 0 0];


                end
            end
            app.isConnected = ~app.isConnected;
            app.StatusLabel.Text = "Disconnected";
            app.RobotStatusLamp.Color = [1 0 0];
            app.connectBtn.Text = "Connect";
            app.TextArea.Value = "[System] Disconnected from robot.";
            delete(app.Camera.Children);
           
            delete(app.VideoTimer);

            drawnow;
            delete(app.tcpobj);


        end

        % Button pushed function: upBtn
        function upBtnButtonPushed(app, event)
            if app.isConnected
                app.TextArea.Value = "[Robot] Moving forward.";
                if app.automode
                    writeline(app.tcpobj,"MOVE FWD");
                else
                    writeline(app.tcpobj,"MOVE FWD"); 
                    pause(0.2);
                    writeline(app.tcpobj,"STOP");
                end
            else
                app.TextArea.Value = "[System] Please connect to the robot to move it.";
            end
        end

        % Button pushed function: rightBtn
        function rightBtnButtonPushed(app, event)
            if app.isConnected
                app.TextArea.Value = "[Robot] Turning right.";
                if app.automode
                    writeline(app.tcpobj,"MOVE RIGHT");
                else
                    writeline(app.tcpobj,"MOVE RIGHT"); 
                    pause(0.2);
                    writeline(app.tcpobj,"STOP");
                end
            else
                app.TextArea.Value = "[System] Please connect to the robot to move it.";
            end
        end

        % Button pushed function: leftBtn
        function leftBtnButtonPushed(app, event)
            if app.isConnected
            
                app.TextArea.Value = "[Robot] Turning left.";
                if app.automode
                    writeline(app.tcpobj,"MOVE LEFT");
                else
                    writeline(app.tcpobj,"MOVE LEFT"); 
                    pause(0.2);
                    writeline(app.tcpobj,"STOP");
                end
            else
                app.TextArea.Value = "[System] Please connect to the robot to move it.";
            end
        end

        % Button pushed function: downBtn
        function downBtnButtonPushed(app, event)
            if app.isConnected
                app.TextArea.Value = "[Robot] Moving backwards.";
                if app.automode
                    writeline(app.tcpobj,"MOVE REV");
                else
                    writeline(app.tcpobj,"MOVE REV"); 
                    pause(0.2);
                    writeline(app.tcpobj,"STOP");
                end
            else
               app.TextArea.Value = "[System] Please connect to the robot to move it.";
            end
        end

        % Button pushed function: stopBtn
        function stopBtnButtonPushed(app, event)
            if app.isConnected
                app.TextArea.Value = "[Robot] Stopping.";
                writeline(app.tcpobj,"STOP");
            else
                app.TextArea.Value = "[System] Please connect to the robot to move it.";
            end
        end

        % Button pushed function: rotateRightBtn
        function rotateRightBtnButtonPushed(app, event)
            if app.isConnected
                app.TextArea.Value = "[Robot] Rotating CW.";
                if app.automode
                    writeline(app.tcpobj,"ROTATE RIGHT");
                else
                    writeline(app.tcpobj,"ROTATE RIGHT"); 
                    pause(0.2);
                    writeline(app.tcpobj,"STOP");
                end
            else
                app.TextArea.Value = "[System] Please connect to the robot to move it.";
            end
        end

        % Button pushed function: rotateLeftBtn
        function rotateLeftBtnButtonPushed(app, event)
            if app.isConnected
                app.TextArea.Value = "[Robot] Rotating CCW.";
                if app.automode
                    writeline(app.tcpobj,"ROTATE LEFT");
                else
                    writeline(app.tcpobj,"ROTATE LEFT"); 
                    pause(0.2);
                    writeline(app.tcpobj,"STOP");
                end
            else
                app.TextArea.Value = "[System] Please connect to the robot to move it.";
            end
           
        end

        % Button pushed function: resetBtn
        function resetBtnButtonPushed(app, event)
            if app.isConnected
                app.TextArea.Value = "[Robot] Resetting servos positions.";
                app.Servo1.Value = 135;
                app.Servo2.Value = 90;
                app.Servo3.Value = 90;
                app.Servo4.Value = 135;
                app.Servo5.Value = 90;
                writeline(app.tcpobj,"RESET");
            else
                app.TextArea.Value = "[System] Please connect to the robot to move it.";
            end
            
        end

        % Button pushed function: autoBtn
        function autoBtnButtonPushed(app, event)
            if app.isConnected
                app.automode= ~app.automode;
                if app.automode
                    app.TextArea.Value = "[System] Auto mode ON.";
                    app.autoBtn.BackgroundColor = [0.15 0.55 0.87];
                else
                    app.TextArea.Value = "[System] Auto mode OFF.";
                    app.autoBtn.BackgroundColor = [0.13 0.13 0.13];
                end
            else
                app.TextArea.Value = "[System] Please connect to the robot to move it.";
            end
        end

        % Callback function
        function lockBtnButtonPushed(app, event)
            app.uiLock= ~app.uiLock;
            if app.uiLock
                app.lockBtn.BackgroundColor = [0.15 0.55 0.87];
            else
                app.lockBtn.BackgroundColor = [0.13 0.13 0.13];
            end
        end

        % Button pushed function: hiBtn
        function hiBtnButtonPushed(app, event)
            if app.isConnected
                app.TextArea.Value = "[Robot] Hi!";
                writeline(app.tcpobj,"HI");
                app.hiBtn.BackgroundColor = [0.15 0.55 0.87];
                pause(4);
                app.hiBtn.BackgroundColor = [0.13 0.13 0.13];
            else
                app.TextArea.Value = "[System] Please connect to the robot to move it.";
            end
        end

        % Button pushed function: enableCameraBtn
        function enableCameraBtnButtonPushed(app, event)
           if (app.isConnected == false)
                app.TextArea.Value = "[System] Please connect to the robot to enable the camera.";
                return
            end
            app.cameraEnabled = ~app.cameraEnabled;

            if app.cameraEnabled 
                app.enableCameraBtn.Text = "Disable Camera";
                app.TextArea.Value = "[System] Camera enabled.";


                % Start timer to read frames asynchronously
                app.VideoTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.015, ... 
                    'TimerFcn', @(~,~) handleVideo(app) ...
                );
                start(app.VideoTimer);
        
            else
                app.enableCameraBtn.Text = "Enable Camera";
                app.TextArea.Value = "[System] Camera disabled.";
                stop(app.VideoTimer);
                delete(app.VideoTimer);
                delete(app.Camera.Children);


            end


        end

        % Button pushed function: screenshotBtn
        function screenshotBtnButtonPushed(app, event)
            userProfile = getenv('USERPROFILE');               % e.g. 'C:\Users\YourName'
            downloadsFolder = fullfile(userProfile, 'Downloads');
        
            if ~exist(downloadsFolder, 'dir')
                % Fallback just in case
                downloadsFolder = pwd;
            end
        
            % 2. Create filename with timestamp
            filename = ['masterpi_' datestr(now, 'yyyymmdd_HHMMSS') '.png'];
            fullpath = fullfile(downloadsFolder, filename);
        
            % 3. Make sure the UIAxes is fully drawn
            drawnow;
        
            % 4. Export whatever is showing in the axes
            exportgraphics(app.Camera, fullpath);  % UIAxes = name of your axes in App Designer
        
            % (Optional) notify user
            app.TextArea.Value = "[System] Screenshot saved in downloads folder.";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Camera = uiaxes(app.UIFigure);
            app.upBtn = uibutton(app.UIFigure, 'push');
            app.upBtn.ButtonPushedFcn = createCallbackFcn(app, @upBtnButtonPushed, true);
            app.upBtn.Text = '▲';
            app.rightBtn = uibutton(app.UIFigure, 'push');
            app.rightBtn.ButtonPushedFcn = createCallbackFcn(app, @rightBtnButtonPushed, true);
            app.rightBtn.Text = '▶';
            app.leftBtn = uibutton(app.UIFigure, 'push');
            app.leftBtn.ButtonPushedFcn = createCallbackFcn(app, @leftBtnButtonPushed, true);
            app.leftBtn.Text = '◀';
            app.downBtn = uibutton(app.UIFigure, 'push');
            app.downBtn.ButtonPushedFcn = createCallbackFcn(app, @downBtnButtonPushed, true);
            app.downBtn.Text = '▼';
            app.stopBtn = uibutton(app.UIFigure, 'push');
            app.stopBtn.ButtonPushedFcn = createCallbackFcn(app, @stopBtnButtonPushed, true);
            app.stopBtn.Text = 'STOP';
            app.hiBtn = uibutton(app.UIFigure, 'push');
            app.hiBtn.ButtonPushedFcn = createCallbackFcn(app, @hiBtnButtonPushed, true);
            app.hiBtn.Text = 'HI';
            app.autoBtn = uibutton(app.UIFigure, 'push');
            app.autoBtn.ButtonPushedFcn = createCallbackFcn(app, @autoBtnButtonPushed, true);
            app.autoBtn.Text = 'AUTO';
            app.Servo1ClippersSliderLabel = uilabel(app.UIFigure);
            app.Servo1ClippersSliderLabel.Text = 'Servo 1 (Clippers)';
            app.Servo1 = uislider(app.UIFigure);
            app.Servo1.Limits = [90 180];
            app.Servo1.ValueChangingFcn = createCallbackFcn(app, @Servo1ValueChanging, true);
            app.Servo1.Value = 90;
            app.Servo2CameraSliderLabel = uilabel(app.UIFigure);
            app.Servo2CameraSliderLabel.Text = 'Servo 2 (Camera)';
            app.Servo2 = uislider(app.UIFigure);
            app.Servo2.Limits = [0 180];
            app.Servo2.ValueChangingFcn = createCallbackFcn(app, @Servo2ValueChanging, true);
            app.Servo3WristSliderLabel = uilabel(app.UIFigure);
            app.Servo3WristSliderLabel.Text = 'Servo 3 (Wrist)';
            app.Servo3 = uislider(app.UIFigure);
            app.Servo3.Limits = [0 180];
            app.Servo3.ValueChangingFcn = createCallbackFcn(app, @Servo3ValueChanging, true);
            app.Servo4ArmSliderLabel = uilabel(app.UIFigure);
            app.Servo4ArmSliderLabel.Text = 'Servo 4 (Arm)';
            app.Servo4 = uislider(app.UIFigure);
            app.Servo4.Limits = [0 270];
            app.Servo4.ValueChangingFcn = createCallbackFcn(app, @Servo4ValueChanging, true);
            app.Servo5BaseSliderLabel = uilabel(app.UIFigure);
            app.Servo5BaseSliderLabel.Text = 'Servo 5 (Base)';
            app.Servo5 = uislider(app.UIFigure);
            app.Servo5.Limits = [0 180];
            app.Servo5.ValueChangingFcn = createCallbackFcn(app, @Servo5ValueChanging, true);
            app.MasterPiControllerLabel = uilabel(app.UIFigure);
            app.MasterPiControllerLabel.Text = '           MasterPi Controller';
            app.RobotStatusLampLabel = uilabel(app.UIFigure);
            app.RobotStatusLampLabel.Text = 'Robot Status';
            app.RobotStatusLamp = uilamp(app.UIFigure);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Disconnected';
            app.connectBtn = uibutton(app.UIFigure, 'push');
            app.connectBtn.ButtonPushedFcn = createCallbackFcn(app, @connectBtnButtonPushed, true);
            app.connectBtn.Text = 'Connect';
            app.enableCameraBtn = uibutton(app.UIFigure, 'push');
            app.enableCameraBtn.ButtonPushedFcn = createCallbackFcn(app, @enableCameraBtnButtonPushed, true);
            app.enableCameraBtn.Text = 'Enable Camera';
            app.screenshotBtn = uibutton(app.UIFigure, 'push');
            app.screenshotBtn.ButtonPushedFcn = createCallbackFcn(app, @screenshotBtnButtonPushed, true);
            app.screenshotBtn.Text = 'Screenshot';
            app.rotateLeftBtn = uibutton(app.UIFigure, 'push');
            app.rotateLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @rotateLeftBtnButtonPushed, true);
            app.rotateLeftBtn.Text = '↶';
            app.rotateRightBtn = uibutton(app.UIFigure, 'push');
            app.rotateRightBtn.ButtonPushedFcn = createCallbackFcn(app, @rotateRightBtnButtonPushed, true);
            app.rotateRightBtn.Text = '↷';
            app.resetBtn = uibutton(app.UIFigure, 'push');
            app.resetBtn.ButtonPushedFcn = createCallbackFcn(app, @resetBtnButtonPushed, true);
            app.resetBtn.Text = 'Reset';
            app.TextArea = uitextarea(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfacing_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end